classdef TempDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TempDisplayUIFigure    matlab.ui.Figure
        MaxTemperatureLabel    matlab.ui.control.Label
        labelMaxTemp           matlab.ui.control.Label
        MinTemperatureLabel    matlab.ui.control.Label
        labelMinTemp           matlab.ui.control.Label
        RelayLampLabel         matlab.ui.control.Label
        RelayLamp              matlab.ui.control.Lamp
        TemperatureGaugeLabel  matlab.ui.control.Label
        TemperatureGauge       matlab.ui.control.Gauge
        RelayCountLabel        matlab.ui.control.Label
        labelRlyCnt            matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        f % udp read function handle
        queueTemp
        queueRly
        queueMaxMin
        queueRlyCnt
        plt % plot temperature 
        fid % log file fid
        pool
    end
    
    methods (Access = private)        

        function onRlyStateRsvd(app,data)
            if data == 1
                app.RelayLamp.Color = 'green';
            elseif data == 0
                app.RelayLamp.Color = 'red';
            end
        end
        
        function onTempRsvd(app,data)
            app.TemperatureGauge.Value = data;
            app.plt.YData = [app.plt.YData(2:end) data];
            fprintf(app.fid, string(datetime));
            fprintf(app.fid, [':' num2str(data) '\n']);
        end

        function onRlyCntRsvd(app,data)
            app.labelRlyCnt.Text = num2str(data);
        end

        function onMaxMinCntRsvd(app,data)
            maxVal = int8(bitsra(data,8));
            minVal = bitand(data,int16(0xff),'int16');
            app.labelMaxTemp.Text = num2str(maxVal);
            app.labelMinTemp.Text = num2str(minVal);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TemperatureGauge.Value = 30;
            app.plt = plot(app.UIAxes, 1:1000, nan(1,1000));
            xlim(app.UIAxes, [1,1000]);
            ylim(app.UIAxes, [-10,50]);
            grid(app.UIAxes, "on");
            %app.u = udpport("LocalPort",8888);
            app.queueTemp = parallel.pool.DataQueue;
            app.queueRly = parallel.pool.DataQueue;
            app.queueMaxMin = parallel.pool.DataQueue;
            app.queueRlyCnt = parallel.pool.DataQueue;
            afterEach(app.queueTemp, @app.onTempRsvd);
            afterEach(app.queueRly, @app.onRlyStateRsvd);
            afterEach(app.queueMaxMin, @app.onMaxMinCntRsvd);
            afterEach(app.queueRlyCnt, @app.onRlyCntRsvd);
            % execute the function
            app.fid = fopen("log.txt","a");
            if exist("backgroundPool", 'builtin') > 0
                app.pool = backgroundPool;
            else
                %delete(gcp('nocreate'));
                app.pool = parpool(1);
            end
            app.f = parfeval(app.pool, @RsvDataFromController, 0,app.queueTemp,app.queueRly,app.queueMaxMin,app.queueRlyCnt);
            
        end

        % Close request function: TempDisplayUIFigure
        function TempDisplayUIFigureCloseRequest(app, event)
            cancel(app.f);
            if ~exist("backgroundPool", 'builtin') > 0
                %delete(app.pool);
                delete(gcp('nocreate'));
            end
            fclose(app.fid);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TempDisplayUIFigure = uifigure('Visible', 'off');
            app.TempDisplayUIFigure.Name = 'TempDisplay';
            app.TempDisplayUIFigure.CloseRequestFcn = createCallbackFcn(app, @TempDisplayUIFigureCloseRequest, true);
            app.MaxTemperatureLabel = uilabel(app.TempDisplayUIFigure);
            app.MaxTemperatureLabel.Text = 'Max Temperature:';
            app.labelMaxTemp = uilabel(app.TempDisplayUIFigure);
            app.labelMaxTemp.Text = '0';
            app.MinTemperatureLabel = uilabel(app.TempDisplayUIFigure);
            app.MinTemperatureLabel.Text = 'Min Temperature:';
            app.labelMinTemp = uilabel(app.TempDisplayUIFigure);
            app.labelMinTemp.Text = '0';
            app.RelayLampLabel = uilabel(app.TempDisplayUIFigure);
            app.RelayLampLabel.Text = 'Relay';
            app.RelayLamp = uilamp(app.TempDisplayUIFigure);
            app.TemperatureGaugeLabel = uilabel(app.TempDisplayUIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.TempDisplayUIFigure, 'circular');
            app.TemperatureGauge.Limits = [10 50];
            app.TemperatureGauge.Value = 10;
            app.RelayCountLabel = uilabel(app.TempDisplayUIFigure);
            app.RelayCountLabel.Text = 'Relay Count:';
            app.labelRlyCnt = uilabel(app.TempDisplayUIFigure);
            app.labelRlyCnt.Text = '0';
            app.UIAxes = uiaxes(app.TempDisplayUIFigure);
            title(app.UIAxes, 'Temp')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.TempDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TempDisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TempDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TempDisplayUIFigure)
        end
    end
end